classdef CSV_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SecondaryDropDown       matlab.ui.control.DropDown
        SecondaryDropDownLabel  matlab.ui.control.Label
        LoadCSVfileButton       matlab.ui.control.Button
        PlotButton              matlab.ui.control.Button
        PlotTypeDropDown        matlab.ui.control.DropDown
        PlotTypeDropDownLabel   matlab.ui.control.Label
        ColumnDropDown          matlab.ui.control.DropDown
        Label                   matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        UIAxes                  matlab.ui.control.UIAxes
    end

properties (Access = private)
    Data table = table()      % the loaded table
    FileName char = ''        % full path of loaded CSV
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadCSVfileButton
        function LoadCSVfileButtonPushed(app, event)
        [file, path] = uigetfile({'*.csv'}, 'Select CSV file');
    if isequal(file,0)
        return; % user cancelled
    end
    fullpath = fullfile(path, file);
    try
        T = readtable(fullpath);
    catch ME
        uialert(app.UIFigure, ['Could not read CSV: ' ME.message], 'File Read Error');
        return;
    end

    % store
    app.Data = T;
    app.FileName = fullpath;

    % populate UI: column dropdown and plot types
    vars = app.Data.Properties.VariableNames;
    if isempty(vars)
        uialert(app.UIFigure, 'No columns found in CSV.', 'Empty file');
        return;
    end

    % Primary column dropdown
    app.ColumnDropDown.Items = vars;
    app.ColumnDropDown.Value = vars{1};

    % Secondary (optional) dropdown: allow (none) choice
    secItems = ['(none)', string(vars)];
    app.SecondaryDropDown.Items = cellstr(secItems);
    if numel(vars) >= 2
        app.SecondaryDropDown.Value = vars{2};
    else
        app.SecondaryDropDown.Value = '(none)';
    end

    % Plot types (include the three 2-column options)
    app.PlotTypeDropDown.Items = {'Histogram','Pie','Bar','PMF','PDF','CDF','Scatter', 'Plot (X,Y)', 'GroupSummary (sum)', 'GeoScatter'};
    app.PlotTypeDropDown.Value = 'Histogram';

    % show preview (first 100 rows)
    nPreview = min(100, height(app.Data));
    app.UITable.Data = app.Data(1:nPreview, :);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
    try
        if isempty(app.Data) || height(app.Data) == 0
            uialert(app.UIFigure, 'Please load a CSV file first.', 'No data');
            return;
        end

        primary = app.ColumnDropDown.Value;
        secondary = app.SecondaryDropDown.Value;
        plotType = app.PlotTypeDropDown.Value;

        % convenience: treat '(none)' as empty
        if ischar(secondary) && strcmp(secondary,'(none)')
            secondary = '';
        end

        % validate primary
        if ~ismember(primary, app.Data.Properties.VariableNames)
            uialert(app.UIFigure, 'Selected primary column not found.', 'Column Error');
            return;
        end

        % Work on a local copy. If dataset is huge, downsample rows for plotting.
        DATA = app.Data;
        nRows = height(DATA);
        MAXROWS_PLOT = 100000;    % absolute maximum rows to hold in memory before sampling
        MAX_KSDENSITY = 20000;    % maximum samples for ksdensity
        if nRows > MAXROWS_PLOT
            idx = randperm(nRows, MAXROWS_PLOT);
            DATA = DATA(idx, :);
            nRows = height(DATA);
            uialert(app.UIFigure, sprintf('Dataset too large — plotting using %d randomly sampled rows.', MAXROWS_PLOT), 'Downsampled');
        end

        col = DATA.(primary);

        cla(app.UIAxes);
        switch plotType
            %% single-column cases
            case 'Histogram'
                if ~isnumeric(col)
                    try
                        numericCol = double(categorical(col));
                        histogram(app.UIAxes, numericCol)
                    catch
                        histogram(app.UIAxes, categorical(col))
                    end
                else
                    histogram(app.UIAxes, col)
                end
                title(app.UIAxes, ['Histogram of ', primary])

            case 'Pie'
                % guard huge category counts
                catcol = categorical(col);
                counts = countcats(catcol);
                cats = categories(catcol);
                % combine small categories into "Other" if too many categories
                MAXCATS = 50;
                if numel(counts) > MAXCATS
                    [countsSorted, idxSort] = sort(counts, 'descend');
                    topIdx = idxSort(1:MAXCATS-1);
                    otherSum = sum(countsSorted(MAXCATS:end));
                    newCounts = [counts(topIdx); otherSum];
                    newCats = [cats(topIdx); {'Other'}];
                    pie(app.UIAxes, newCounts, newCats)
                else
                    pie(app.UIAxes, counts, cats)
                end
                title(app.UIAxes, ['Pie of ', primary])

            case 'PMF'
                % if too many unique values, use binned PMF
                if ~isnumeric(col)
                    [u, ~, idx] = unique(col);
                    counts = accumarray(idx, 1);
                    pmf = counts / sum(counts);
                    % limit categories
                    if numel(pmf) > 200
                        % show top 50 categories only
                        [~, order] = sort(pmf, 'descend');
                        top = order(1:min(50,numel(order)));
                        pmfTop = pmf(top);
                        lbls = cellstr(string(u(top)));
                        pmfTop(end+1) = sum(pmf) - sum(pmfTop); %#ok<AGROW>
                        lbls{end+1} = 'Other';
                        bar(app.UIAxes, pmfTop)
                        app.UIAxes.XTickLabel = lbls;
                        xtickangle(app.UIAxes,45)
                    else
                        bar(app.UIAxes, pmf)
                        app.UIAxes.XTickLabel = cellstr(string(u));
                        xtickangle(app.UIAxes,45)
                    end
                    ylabel(app.UIAxes, 'P(X=x)')
                else
                    vals = col;
                    [u, ~, idx] = unique(vals);
                    counts = accumarray(idx, 1);
                    pmf = counts / sum(counts);
                    if numel(pmf) > 200
                        % switch to binned pmf using histcounts
                        [countsB, edges] = histcounts(vals, 50);
                        bar(app.UIAxes, countsB / sum(countsB))
                        xlabel(app.UIAxes, primary)
                    else
                        bar(app.UIAxes, pmf)
                        app.UIAxes.XTickLabel = cellstr(string(u));
                        xtickangle(app.UIAxes,45)
                    end
                    ylabel(app.UIAxes, 'P(X=x)')
                end
                title(app.UIAxes, ['PMF of ', primary])

            case 'PDF'
                if ~isnumeric(col)
                    uialert(app.UIFigure, 'PDF requires numeric data.', 'Type Error');
                    return;
                end
                nSamples = numel(col);
                if nSamples > MAX_KSDENSITY
                    % downsample for density estimation
                    idx = randperm(nSamples, min(nSamples, MAX_KSDENSITY));
                    colForDensity = col(idx);
                else
                    colForDensity = col;
                end
                % try ksdensity; fallback to normalized histogram
                try
                    [f, xi] = ksdensity(colForDensity);
                    plot(app.UIAxes, xi, f, 'LineWidth', 1.3);
                    xlabel(app.UIAxes, primary)
                    ylabel(app.UIAxes, 'Estimated pdf')
                catch
                    histogram(app.UIAxes, col, 'Normalization', 'pdf')
                    ylabel(app.UIAxes, 'pdf (histogram)')
                end
                title(app.UIAxes, ['PDF (est.) of ', primary])

            case 'CDF'
                if ~isnumeric(col)
                    uialert(app.UIFigure, 'CDF requires numeric data.', 'Type Error');
                    return;
                end
                [f, x] = ecdf(col);
                plot(app.UIAxes, x, f, 'LineWidth', 1.3)
                xlabel(app.UIAxes, primary)
                ylabel(app.UIAxes, 'CDF')
                title(app.UIAxes, ['CDF of ', primary])

            case 'Scatter'
                if ~isnumeric(col)
                    uialert(app.UIFigure, 'Scatter uses numeric data.', 'Type Error');
                    return;
                end
                plot(app.UIAxes, col, '.-')
                xlabel(app.UIAxes, 'Index')
                ylabel(app.UIAxes, primary)
                title(app.UIAxes, ['Scatter/Signal of ', primary])

            %% two-column cases (secondary must be provided)
            case 'Plot (X,Y)'
                if isempty(secondary)
                    uialert(app.UIFigure, 'Select a second column for Y in the "Second Column" dropdown.', 'Missing Y');
                    return;
                end
                if ~ismember(secondary, DATA.Properties.VariableNames)
                    uialert(app.UIFigure, 'Selected second column not found.', 'Column Error');
                    return;
                end
                x = DATA.(primary);
                y = DATA.(secondary);

                % basic conversion if needed
                if ~isnumeric(x)
                    if isdatetime(x); x = datenum(x); else x = double(categorical(x)); end
                end
                if ~isnumeric(y)
                    if isdatetime(y); y = datenum(y); else y = double(categorical(y)); end
                end

                % downsample if too many points to render
                N = numel(x);
                MAX_PLOT_POINTS = 50000;
                if N > MAX_PLOT_POINTS
                    idx = randperm(N, MAX_PLOT_POINTS);
                    x = x(idx); y = y(idx);
                    uialert(app.UIFigure, sprintf('Plotting a sample of %d points for speed.', MAX_PLOT_POINTS), 'Downsampled');
                end

                plot(app.UIAxes, x, y, '.-')
                xlabel(app.UIAxes, primary)
                ylabel(app.UIAxes, secondary)
                title(app.UIAxes, [secondary ' vs ' primary])

            case 'GroupSummary (sum)'
                if isempty(secondary)
                    uialert(app.UIFigure, 'Select a second column to SUM in the "Second Column" dropdown.', 'Missing target');
                    return;
                end
                if ~ismember(secondary, DATA.Properties.VariableNames)
                    uialert(app.UIFigure, 'Selected second column not found.', 'Column Error');
                    return;
                end
                % Use a robust aggregation using splitapply (faster and predictable)
                grp = DATA.(primary);
                values = DATA.(secondary);
                % ensure numeric for sum
                if ~isnumeric(values)
                    % attempt conversion
                    try
                        values = double(values);
                    catch
                        uialert(app.UIFigure, 'Target column must be numeric (or convertible).', 'Type Error');
                        return;
                    end
                end
                % convert group to categorical (for consistent grouping)
                G = categorical(grp);
                [groupCats, ~, ic] = unique(G);
                sums = accumarray(ic, double(values), [], @sum, 0);

                % combine into table and limit number of groups shown
                Tgroup = table(groupCats, sums, 'VariableNames', {primary, ['sum_' secondary]});
                % sort descending by sum and keep top 50 for plotting
                [sumsSorted, order] = sort(sums, 'descend');
                TOP = min(numel(sumsSorted), 50);
                topIdx = order(1:TOP);
                xvals = groupCats(topIdx);
                yvals = sumsSorted(1:TOP);

                bar(app.UIAxes, yvals)
                app.UIAxes.XTick = 1:numel(xvals);
                app.UIAxes.XTickLabel = cellstr(string(xvals));
                xtickangle(app.UIAxes,45)
                ylabel(app.UIAxes, ['Sum(' secondary ')'])
                title(app.UIAxes, ['Sum of ' secondary ' grouped by ' primary])

            case 'GeoScatter'
                if isempty(secondary)
                    uialert(app.UIFigure, 'For GeoScatter: select longitude in "Second Column" and latitude in main Column.', 'Missing coords');
                    return;
                end
                if ~ismember(secondary, DATA.Properties.VariableNames)
                    uialert(app.UIFigure, 'Selected second column not found.', 'Column Error');
                    return;
                end
                lat = DATA.(primary);
                lng = DATA.(secondary);
                if ~isnumeric(lat) || ~isnumeric(lng)
                    uialert(app.UIFigure, 'Latitude and Longitude columns must be numeric.', 'Type Error');
                    return;
                end

                % downsample for plotting if too many points
                N = numel(lat);
                MAX_SCATTER = 50000;
                if N > MAX_SCATTER
                    idx = randperm(N, MAX_SCATTER);
                    lat = lat(idx);
                    lng = lng(idx);
                    uialert(app.UIFigure, sprintf('GeoScatter plotting sampled %d points for speed.', MAX_SCATTER), 'Downsampled');
                end

                % prefer geoscatter if available; fallback to scatter in UIAxes
                if exist('geoscatter','file') == 2
                    try
                        % geoscatter usually creates a geographic axes; use a new figure to avoid parent issues
                        fig = figure('Name','GeoScatter','NumberTitle','off');
                        geoscatter(lat, lng, 20, 'filled')
                        title('GeoScatter (lat,lng)')
                    catch
                        scatter(app.UIAxes, lng, lat, 20, 'filled')
                        xlabel(app.UIAxes, 'Longitude')
                        ylabel(app.UIAxes, 'Latitude')
                        title(app.UIAxes, 'GeoScatter (lng vs lat)')
                    end
                else
                    scatter(app.UIAxes, lng, lat, 20, 'filled')
                    xlabel(app.UIAxes, 'Longitude')
                    ylabel(app.UIAxes, 'Latitude')
                    title(app.UIAxes, 'GeoScatter (lng vs lat)')
                end

            otherwise
                uialert(app.UIFigure, 'Unknown plot type selected.', 'Plot Error');
        end

    catch ME
        % show error alert instead of letting MATLAB crash/hang
        uialert(app.UIFigure, sprintf('Plot failed: %s', ME.message), 'Plot Error');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'UIAxes')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ColumnDropDown';
            app.ColumnDropDown = uidropdown(app.UIFigure);
            app.PlotTypeDropDownLabel = uilabel(app.UIFigure);
            app.PlotTypeDropDownLabel.Text = 'PlotTypeDropDown';
            app.PlotTypeDropDown = uidropdown(app.UIFigure);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'PlotButton';
            app.LoadCSVfileButton = uibutton(app.UIFigure, 'push');
            app.LoadCSVfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCSVfileButtonPushed, true);
            app.LoadCSVfileButton.Text = 'Load CSV file';
            app.SecondaryDropDownLabel = uilabel(app.UIFigure);
            app.SecondaryDropDownLabel.Text = 'SecondaryDropDown';
            app.SecondaryDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CSV_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end