classdef DigitalSimApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DigitalLogicDigitalModulationLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        DigitalLogicDesignTab           matlab.ui.container.Tab
        TruthTableLabel                 matlab.ui.control.Label
        XNORButton                      matlab.ui.control.Button
        NORButton                       matlab.ui.control.Button
        NANDButton                      matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        XORButton                       matlab.ui.control.Button
        NOTButton                       matlab.ui.control.Button
        ORButton                        matlab.ui.control.Button
        ANDButton                       matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        DigitalModulationTab            matlab.ui.container.Tab
        PhaseEditField                  matlab.ui.control.EditField
        PhaseEditFieldLabel             matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.EditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        BEREditField                    matlab.ui.control.NumericEditField
        BEREditFieldLabel               matlab.ui.control.Label
        SimulateModulationButton        matlab.ui.control.Button
        SelectModulationDropDown        matlab.ui.control.DropDown
        SelectModulationDropDownLabel   matlab.ui.control.Label
        InputBinaryEditField            matlab.ui.control.EditField
        InputBinaryEditFieldLabel       matlab.ui.control.Label
        ConstellationAxes               matlab.ui.control.UIAxes
        SignalAxes                      matlab.ui.control.UIAxes
        KMAPTab                         matlab.ui.container.Tab
        SimplifiedExpressionTextArea    matlab.ui.control.TextArea
        SimplifiedExpressionTextAreaLabel  matlab.ui.control.Label
        TruthTableTextArea              matlab.ui.control.TextArea
        TruthTableInputTextAreaLabel    matlab.ui.control.Label
        BooleanExpressionEditField      matlab.ui.control.EditField
        BooleanExpressionEditFieldLabel  matlab.ui.control.Label
        VariableCountDropDown           matlab.ui.control.DropDown
        NumberofVariablesDropDownLabel  matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        GroupsLabel                     matlab.ui.control.Label
        GenerateKMapButton              matlab.ui.control.Button
        KMapAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ANDButton
        function ANDButtonPushed(app, event)
            inputs = [0 0; 0 1; 1 0; 1 1];
    outputs = inputs(:,1) & inputs(:,2);

    % Update table
    app.UITable.Data = table(inputs(:,1), inputs(:,2), outputs, ...
        'VariableNames', {'A','B','A_AND_B'});

   % Update Logic Diagram (proper AND gate shape)
cla(app.UIAxes); hold(app.UIAxes,'on');

%% Inputs lines
    plot(app.UIAxes,[-0.5 0],[0.25 0.25],'k','LineWidth',2);
    plot(app.UIAxes,[-0.5 0],[0.75 0.75],'k','LineWidth',2);

    %% AND gate body
    % Draw left rectangle
    plot(app.UIAxes,[0 0],[0 1],'k','LineWidth',2);   % left vertical
    plot(app.UIAxes,[0 0.5],[0 0],'k','LineWidth',2); % bottom horizontal
    plot(app.UIAxes,[0 0.5],[1 1],'k','LineWidth',2); % top horizontal

    % Draw semicircle on right side
    theta = linspace(-pi/2,pi/2,100);
    x = 0.5 + 0.5*cos(theta);   % shift right by 0.5
    y = 0.5 + 0.5*sin(theta);   % centered vertically at 0.5
    plot(app.UIAxes,x,y,'k','LineWidth',2);

    %% Output line
    plot(app.UIAxes,[1 1.3],[0.5 0.5],'k','LineWidth',2);

    %% Labels
    text(app.UIAxes,-0.55,0.75,'A','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,-0.55,0.25,'B','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,1.35,0.5,'Y','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,0.15,-0.15,'AND','FontSize',10,'FontWeight','bold');
    text(app.UIAxes,0.1,1.1,'Logic Diagram','FontSize',12,'FontWeight','bold');

    %% Formatting
    axis(app.UIAxes,'equal');
    axis(app.UIAxes,'off');

    % Plot waveform (Output vs Time/Index)
    stairs(app.UIAxes2,0:4,outputs([1:end 1]),'LineWidth',2)
    ylim(app.UIAxes2,[-0.2 1.2])
    title(app.UIAxes2, 'AND Output')
        end

        % Button pushed function: ORButton
        function ORButtonPushed(app, event)
            % Truth table
    inputs = [0 0; 0 1; 1 0; 1 1];
    outputs = inputs(:,1) | inputs(:,2);

    % Update table
    app.UITable.Data = table(inputs(:,1), inputs(:,2), outputs, ...
        'VariableNames', {'A','B','A_OR_B'});

    % Gate diagram
    cla(app.UIAxes); hold(app.UIAxes,'on');

    % Input lines
    plot(app.UIAxes,[-0.6 -0.1],[0.25 0.25],'k','LineWidth',2);
    plot(app.UIAxes,[-0.6 -0.1],[0.75 0.75],'k','LineWidth',2);

    % Main OR arc (front)
    theta = linspace(-pi/2,pi/2,200);
    x1 = 0.8*cos(theta);
    y1 = 0.5 + 0.5*sin(theta);
    plot(app.UIAxes,x1,y1,'k','LineWidth',2);

    % Back arc (concave side)
    x2 = 0.2*cos(theta) - 0.3;
    y2 = 0.5 + 0.5*sin(theta);
    plot(app.UIAxes,x2,y2,'k','LineWidth',2);

    % Output line
    plot(app.UIAxes,[0.8 1.2],[0.5 0.5],'k','LineWidth',2);

    % Labels
    text(app.UIAxes,-0.65,0.75,'A','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,-0.65,0.25,'B','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,1.25,0.5,'Y','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,0.1,-0.15,'OR','FontSize',10,'FontWeight','bold');
    text(app.UIAxes,0.1,1.1,'Logic Diagram','FontSize',12,'FontWeight','bold');

    axis(app.UIAxes,'equal'); axis(app.UIAxes,'off');


% Waveform
cla(app.UIAxes2);
t = 0:4;
y = outputs([1:end 1]);
stairs(app.UIAxes2,t,y,'LineWidth',2);
ylim(app.UIAxes2,[-0.2 1.2]);
xlim(app.UIAxes2,[0 4]);
title(app.UIAxes2,'OR Output'); xlabel(app.UIAxes2, 'Time Step'); ylabel(app.UIAxes2, 'Logic Level');
        end

        % Button pushed function: NOTButton
        function NOTButtonPushed(app, event)
         % Truth table
    inputs = [0; 1];
    outputs = ~inputs;

    % Update table
    app.UITable.Data = table(inputs, outputs, ...
        'VariableNames', {'A','NOT_A'});

    % Gate diagram
    cla(app.UIAxes); hold(app.UIAxes,'on');

    % Input line
    plot(app.UIAxes,[-0.5 0],[0.5 0.5],'k','LineWidth',2);

    % Triangle
    fill(app.UIAxes,[0 0 0.7],[0 1 0.5],'w','EdgeColor','k','LineWidth',2);

    % Bubble
    viscircles(app.UIAxes,[0.75 0.5],0.05,'Color','k','LineWidth',2);

    % Output line
    plot(app.UIAxes,[0.8 1.2],[0.5 0.5],'k','LineWidth',2);

    % Labels
    text(app.UIAxes,-0.55,0.5,'A','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,1.25,0.5,'Y','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,0.2,-0.15,'NOT','FontSize',10,'FontWeight','bold');
    text(app.UIAxes,0.2,1.1,'Logic Diagram','FontSize',12,'FontWeight','bold');
    axis(app.UIAxes,'equal'); axis(app.UIAxes,'off');

    % Waveform
    cla(app.UIAxes2);
    t = 0:2;
    y = outputs([1:end 1]);
    stairs(app.UIAxes2,t,y,'LineWidth',2);
    ylim(app.UIAxes2,[-0.2 1.2]);
    xlim(app.UIAxes2,[0 2]); % Adjusted x-limit for 2 data points
    title(app.UIAxes2,'NOT Output');
    xlabel(app.UIAxes2, 'Time Step');
    ylabel(app.UIAxes2, 'Logic Level');
        end

        % Button pushed function: XORButton
        function XORButtonPushed(app, event)
             % Truth table
    inputs = [0 0; 0 1; 1 0; 1 1];
    outputs = xor(inputs(:,1), inputs(:,2));

    % Update table
    app.UITable.Data = table(inputs(:,1), inputs(:,2), outputs, ...
        'VariableNames', {'A','B','A_XOR_B'});

    % Gate diagram
    cla(app.UIAxes); hold(app.UIAxes,'on');

    % Input lines
    plot(app.UIAxes,[-0.6 -0.1],[0.25 0.25],'k','LineWidth',2);
    plot(app.UIAxes,[-0.6 -0.1],[0.75 0.75],'k','LineWidth',2);

    % Main OR arc (front)
    theta = linspace(-pi/2,pi/2,200);
    x1 = 0.8*cos(theta);
    y1 = 0.5 + 0.5*sin(theta);
    plot(app.UIAxes,x1,y1,'k','LineWidth',2);

    % Back arc (concave side)
    x2 = 0.2*cos(theta) - 0.3;
    y2 = 0.5 + 0.5*sin(theta);
    plot(app.UIAxes,x2,y2,'k','LineWidth',2);

    % Extra XOR arc (parallel to back curve)
    x3 = 0.2*cos(theta) - 0.4;
    y3 = 0.5 + 0.5*sin(theta);
    plot(app.UIAxes,x3,y3,'k','LineWidth',2);

    % Output line
    plot(app.UIAxes,[0.8 1.2],[0.5 0.5],'k','LineWidth',2);

    % Labels
    text(app.UIAxes,-0.65,0.75,'A','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,-0.65,0.25,'B','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,1.25,0.5,'Y','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,0.1,-0.15,'XOR','FontSize',10,'FontWeight','bold');
    text(app.UIAxes,0.1,1.1,'Logic Diagram','FontSize',12,'FontWeight','bold');

    axis(app.UIAxes,'equal'); axis(app.UIAxes,'off');

    % Waveform
    cla(app.UIAxes2);
    t = 0:4;
    y = outputs([1:end 1]);
    stairs(app.UIAxes2,t,y,'LineWidth',2);
    ylim(app.UIAxes2,[-0.2 1.2]);
    xlim(app.UIAxes2,[0 4]);
    title(app.UIAxes2,'XOR Output');
    xlabel(app.UIAxes2, 'Time Step');
    ylabel(app.UIAxes2, 'Logic Level');
        end

        % Button pushed function: NANDButton
        function NANDButtonPushed(app, event)
            % Truth table
    inputs = [0 0; 0 1; 1 0; 1 1];
    outputs = ~(inputs(:,1) & inputs(:,2));

    % Update table
    app.UITable.Data = table(inputs(:,1), inputs(:,2), outputs, ...
        'VariableNames', {'A','B','A_NAND_B'});

    % Gate diagram
    cla(app.UIAxes); hold(app.UIAxes,'on');

    % Input lines
    plot(app.UIAxes,[-0.5 0],[0.25 0.25],'k','LineWidth',2);
    plot(app.UIAxes,[-0.5 0],[0.75 0.75],'k','LineWidth',2);

    % AND body
    plot(app.UIAxes,[0 0],[0 1],'k','LineWidth',2);
    plot(app.UIAxes,[0 0.5],[0 0],'k','LineWidth',2);
    plot(app.UIAxes,[0 0.5],[1 1],'k','LineWidth',2);
    theta = linspace(-pi/2,pi/2,100);
    x = 0.5 + 0.5*cos(theta);
    y = 0.5 + 0.5*sin(theta);
    plot(app.UIAxes,x,y,'k','LineWidth',2);

    % Bubble
    viscircles(app.UIAxes,[1 0.5],0.05,'Color','k','LineWidth',2);

    % Output line
    plot(app.UIAxes,[1.05 1.3],[0.5 0.5],'k','LineWidth',2);

    % Labels
    text(app.UIAxes,-0.55,0.75,'A','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,-0.55,0.25,'B','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,1.35,0.5,'Y','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,0.1,-0.15,'NAND','FontSize',10,'FontWeight','bold');
    text(app.UIAxes,0.1,1.1,'Logic Diagram','FontSize',12,'FontWeight','bold');

    axis(app.UIAxes,'equal'); axis(app.UIAxes,'off');

    % Waveform
    cla(app.UIAxes2);
    t = 0:4;
    y = outputs([1:end 1]);
    stairs(app.UIAxes2,t,y,'LineWidth',2);
    ylim(app.UIAxes2,[-0.2 1.2]);
    xlim(app.UIAxes2,[0 4]);
    title(app.UIAxes2,'NAND Output');
    xlabel(app.UIAxes2, 'Time Step');
    ylabel(app.UIAxes2, 'Logic Level');
        end

        % Button pushed function: NORButton
        function NORButtonPushed(app, event)
              % Truth table
    inputs = [0 0; 0 1; 1 0; 1 1];
    outputs = ~(inputs(:,1) | inputs(:,2));

    % Update table
    app.UITable.Data = table(inputs(:,1), inputs(:,2), outputs, ...
        'VariableNames', {'A','B','A_NOR_B'});

    % Gate diagram
    cla(app.UIAxes); hold(app.UIAxes,'on');

    % Input lines
    plot(app.UIAxes,[-0.6 -0.1],[0.25 0.25],'k','LineWidth',2);
    plot(app.UIAxes,[-0.6 -0.1],[0.75 0.75],'k','LineWidth',2);

    % OR body
    theta = linspace(-pi/2,pi/2,200);
    x1 = 0.8*cos(theta);
    y1 = 0.5 + 0.5*sin(theta);
    plot(app.UIAxes,x1,y1,'k','LineWidth',2);

    x2 = 0.2*cos(theta) - 0.3;
    y2 = 0.5 + 0.5*sin(theta);
    plot(app.UIAxes,x2,y2,'k','LineWidth',2);

    % Bubble
    viscircles(app.UIAxes,[0.85 0.5],0.05,'Color','k','LineWidth',2);

    % Output line
    plot(app.UIAxes,[0.9 1.2],[0.5 0.5],'k','LineWidth',2);

    % Labels
    text(app.UIAxes,-0.65,0.75,'A','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,-0.65,0.25,'B','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,1.25,0.5,'Y','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,0.1,-0.15,'NOR','FontSize',10,'FontWeight','bold');
    text(app.UIAxes,0.1,1.1,'Logic Diagram','FontSize',12,'FontWeight','bold');

    axis(app.UIAxes,'equal'); axis(app.UIAxes,'off');

     % Waveform
    cla(app.UIAxes2);
    t = 0:4;
    y = outputs([1:end 1]);
    stairs(app.UIAxes2,t,y,'LineWidth',2);
    ylim(app.UIAxes2,[-0.2 1.2]);
    xlim(app.UIAxes2,[0 4]);
    title(app.UIAxes2,'NOR Output');
    xlabel(app.UIAxes2, 'Time Step');
    ylabel(app.UIAxes2, 'Logic Level');
        end

        % Button pushed function: XNORButton
        function XNORButtonPushed(app, event)
             % Truth table
    inputs = [0 0; 0 1; 1 0; 1 1];
    outputs = ~xor(inputs(:,1), inputs(:,2));

    % Update table
    app.UITable.Data = table(inputs(:,1), inputs(:,2), outputs, ...
        'VariableNames', {'A','B','A_XNOR_B'});

    % Gate diagram
    cla(app.UIAxes); hold(app.UIAxes,'on');

    % Input lines
    plot(app.UIAxes,[-0.6 -0.1],[0.25 0.25],'k','LineWidth',2);
    plot(app.UIAxes,[-0.6 -0.1],[0.75 0.75],'k','LineWidth',2);

    % OR body
    theta = linspace(-pi/2,pi/2,200);
    x1 = 0.8*cos(theta);
    y1 = 0.5 + 0.5*sin(theta);
    plot(app.UIAxes,x1,y1,'k','LineWidth',2);

    x2 = 0.2*cos(theta) - 0.3;
    y2 = 0.5 + 0.5*sin(theta);
    plot(app.UIAxes,x2,y2,'k','LineWidth',2);

    % Extra XOR curve
    x3 = 0.2*cos(theta) - 0.4;
    y3 = 0.5 + 0.5*sin(theta);
    plot(app.UIAxes,x3,y3,'k','LineWidth',2);

    % Bubble
    viscircles(app.UIAxes,[0.85 0.5],0.05,'Color','k','LineWidth',2);

    % Output line
    plot(app.UIAxes,[0.9 1.2],[0.5 0.5],'k','LineWidth',2);

    % Labels
    text(app.UIAxes,-0.65,0.75,'A','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,-0.65,0.25,'B','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,1.25,0.5,'Y','FontSize',12,'FontWeight','bold');
    text(app.UIAxes,0.1,-0.15,'XNOR','FontSize',10,'FontWeight','bold');
    text(app.UIAxes,0.1,1.1,'Logic Diagram','FontSize',12,'FontWeight','bold');

    axis(app.UIAxes,'equal'); axis(app.UIAxes,'off');

    % Waveform
    cla(app.UIAxes2);
    t = 0:4;
    y = outputs([1:end 1]);
    stairs(app.UIAxes2,t,y,'LineWidth',2);
    ylim(app.UIAxes2,[-0.2 1.2]);
    xlim(app.UIAxes2,[0 4]);
    title(app.UIAxes2,'XNOR Output');
    xlabel(app.UIAxes2, 'Time Step');
    ylabel(app.UIAxes2, 'Logic Level');
        end

        % Button pushed function: SimulateModulationButton
        function SimulateModulationButtonPushed(app, event)
           
    % ---- Input parsing ----
    binDataStr = app.InputBinaryEditField.Value;
    if isempty(binDataStr)
        uialert(app.UIFigure, 'Enter a valid binary sequence (e.g., 10110)', 'Invalid Input');
        return;
    end
    binData = double(binDataStr - '0');     % '101' -> [1 0 1]
    if any(~ismember(binData,[0 1]))
        uialert(app.UIFigure, 'Binary string must contain only 0 and 1', 'Invalid Input');
        return;
    end
    app.txBitStream = binData;

    % ---- Modulation parameters ----
    modType = app.SelectModulationDropDown.Value;
    amp = str2double(app.AmplitudeEditField.Value); if isnan(amp), amp = 1; end
    phs = str2double(app.PhaseEditField.Value);     if isnan(phs), phs = 0; end

    % ---- Build symbols depending on modulation ----
    try
        switch modType
            case 'ASK'
                M = 2; app.kSymbols = 1;
                % simple on/off ASK: 0->0, 1->amp
                app.txSymbols = complex(binData .* amp);

            case 'BPSK'
                M = 2; app.kSymbols = 1;
                % map 0->-1, 1->+1 (scaled by amp)
                app.txSymbols = complex((binData*2 - 1) * amp);

            case 'PSK'    % choose 4-PSK as default PSK example
                M = 4; app.kSymbols = 2;
                [sym, bitsMat] = app.bits2symbols(binData, M);
                app.txBitsMatrix = bitsMat;
                app.txSymbols = pskmod(sym, M, pi/M);

            case 'QPSK'
                M = 4; app.kSymbols = 2;
                [sym, bitsMat] = app.bits2symbols(binData, M);
                app.txBitsMatrix = bitsMat;
                app.txSymbols = pskmod(sym, M, pi/4);

            case 'FSK'
                M = 2; app.kSymbols = 1;
                % FSK returns complex symbols (requires Communications Toolbox)
                app.txSymbols = fskmod(binData, M, 2, 1, 1);

            case 'QAM'
                % interpret AmplitudeEditField as amplitude-levels and PhaseEditField as phase-levels
                AmpLevels = max(2, round(amp));
                PhaseLevels = max(1, round(phs));
                M = AmpLevels * PhaseLevels;
                % ensure M is power of two for standard qammod usage:
                if ~isequal(2^round(log2(M)), M)
                    % choose closest power of two
                    M = 2^round(log2(M));
                end
                k = round(log2(M)); app.kSymbols = k;
                [sym, bitsMat] = app.bits2symbols(binData, M);
                app.txBitsMatrix = bitsMat;
                app.txSymbols = qammod(sym, M);

            otherwise
                uialert(app.UIFigure, 'Unknown modulation type', 'Error');
                return;
        end
    catch ME
        uialert(app.UIFigure, ['Modulation error: ' ME.message], 'Error');
        return;
    end

    % ---- Prepare plotting data ----
    N = numel(app.txSymbols);
    app.txTime = 0:(N-1);
    app.txWaveform = real(app.txSymbols);    % crude "waveform" (sample values plotted)
    % (If you want a real oversampled analog waveform, we can generate by pulse-shaping later)

    % ---- Plot binary bits and modulated symbol samples on SignalAxes ----
    cla(app.SignalAxes);
    hold(app.SignalAxes,'on');
    % binary stair (bit index on x)
    hBin = stairs(app.SignalAxes, 0:length(app.txBitStream)-1, app.txBitStream, 'LineWidth', 1.2);
    % modulated sample points (one point per symbol)
    hMod = plot(app.SignalAxes, app.txTime, app.txWaveform, '-o', 'MarkerSize', 6, 'LineWidth', 1.2);
    xlabel(app.SignalAxes, 'Index');
    ylabel(app.SignalAxes, 'Amplitude');
    title(app.SignalAxes, 'Binary Signal & Modulated Waveform');
    legend(app.SignalAxes, 'Input bits', 'Modulated samples', 'Location','best');

    % attach callbacks to the plotted objects so clicks anywhere on the object call our handler
    set(hBin, 'ButtonDownFcn', @(src,evt)app.SignalAxesObjectClicked(src, evt));
    set(hMod, 'ButtonDownFcn', @(src,evt)app.SignalAxesObjectClicked(src, evt));

    hold(app.SignalAxes,'off');
    app.signalPlotHandle = [hBin; hMod];

    % ---- Plot constellation inside app.ConstellationAxes (no new figure) ----
    cla(app.ConstellationAxes);
    hold(app.ConstellationAxes,'on');
    hConst = plot(app.ConstellationAxes, real(app.txSymbols), imag(app.txSymbols), 'o', 'MarkerSize', 8, 'LineWidth', 1.2);
    xlabel(app.ConstellationAxes, 'I');
    ylabel(app.ConstellationAxes, 'Q');
    title(app.ConstellationAxes, 'Constellation Diagram');

    % attach callback to constellation markers
    set(hConst, 'ButtonDownFcn', @(src,evt)app.ConstellationAxesObjectClicked(src, evt));

    hold(app.ConstellationAxes,'off');
    app.constellationPlotHandle = hConst;

    % ---- Simple BER estimate (AWGN) ----
    % For demonstration: send through AWGN and demodulate (best-effort)
    try
        snr = 10; % fixed SNR for display
        rx = awgn(app.txSymbols, snr, 'measured');

        switch modType
            case {'ASK','BPSK'}
                % crude decision for 1-bit constellations
                rxBits = real(rx) > 0;
                rxBits = rxBits(:).'; % row
            case {'PSK','QPSK'}
                rxSym = pskdemod(rx, M, pi/M);
                rxBits = app.symbols2bits(rxSym, M);
            case 'FSK'
                rxSym = fskdemod(rx, M, 2, 1, 1);
                rxBits = app.symbols2bits(rxSym, M);
            case 'QAM'
                rxSym = qamdemod(rx, M);
                rxBits = app.symbols2bits(rxSym, M);
            otherwise
                rxBits = app.txBitStream;
        end

        % trim rxBits to original input length (in case we padded)
        rxBits = rxBits(1:length(app.txBitStream));
        ber = sum(rxBits ~= app.txBitStream) / length(app.txBitStream);
    catch
        ber = 0;
    end

    app.BEREditField.Value = num2str(ber);
        end

        % Size changed function: KMAPTab
        function generateKMap(app, event)
            % Get number of variables
            app.NumVars = str2double(app.VariableCountDropDown.Value);
            
            % Parse input (from truth table text area)
            mintermStr = app.TruthTableTextArea.Value;
            if ~isempty(mintermStr)
                try
                    % Parse minterms
                    app.Minterms = str2num(mintermStr{1});
                    
                    % Generate K-map
                    drawKMap(app);
                    
                    % Find prime implicants and simplify
                    simplifyExpression(app);
                catch
                    uialert(app.UIFigure, 'Invalid minterm format. Use comma-separated numbers.', 'Input Error');
                end
            else
                uialert(app.UIFigure, 'Please enter minterms', 'Input Error');
            end
        end
        
        function drawKMap(app)
            cla(app.KMapAxes);
            hold(app.KMapAxes, 'on');
            
            switch app.NumVars
                case 2
                    drawKMap2Var(app);
                case 3
                    drawKMap3Var(app);
                case 4
                    drawKMap4Var(app);
                case 5
                    drawKMap5Var(app);
            end
            
            hold(app.KMapAxes, 'off');
        end
        
        function drawKMap2Var(app)
            % 2-variable K-map (2x2)
            rows = 2; cols = 2;
            
            % Gray code for 2 variables
            rowLabels = {'0', '1'};
            colLabels = {'0', '1'};
            
            % Initialize K-map
            kmap = zeros(rows, cols);
            
            % Fill K-map with minterms
            for m = app.Minterms
                if m >= 0 && m < 4
                    row = floor(m/2) + 1;
                    col = mod(m, 2) + 1;
                    kmap(row, col) = 1;
                end
            end
            
            % Draw the K-map grid
            app.KMapAxes.XLim = [0 cols];
            app.KMapAxes.YLim = [0 rows];
            app.KMapAxes.YDir = 'reverse';
            app.KMapAxes.XTick = 0.5:1:cols-0.5;
            app.KMapAxes.YTick = 0.5:1:rows-0.5;
            app.KMapAxes.XTickLabel = colLabels;
            app.KMapAxes.YTickLabel = rowLabels;
            app.KMapAxes.GridAlpha = 0.5;
            grid(app.KMapAxes, 'on');
            
            % Add labels
            xlabel(app.KMapAxes, 'B');
            ylabel(app.KMapAxes, 'A');
            title(app.KMapAxes, '2-Variable K-Map');
            
            % Draw cells and values
            for i = 1:rows
                for j = 1:cols
                    % Draw rectangle
                    rectangle(app.KMapAxes, 'Position', [j-1, i-1, 1, 1], ...
                        'FaceColor', [1 1 1], 'EdgeColor', 'k', 'LineWidth', 1.5);
                    
                    % Add value
                    text(app.KMapAxes, j-0.5, i-0.5, num2str(kmap(i,j)), ...
                        'HorizontalAlignment', 'center', ...
                        'VerticalAlignment', 'middle', ...
                        'FontSize', 14, 'FontWeight', 'bold');
                end
            end
            
            app.KMapData = kmap;
        end
        
        function drawKMap3Var(app)
            % 3-variable K-map (2x4)
            rows = 2; cols = 4;
            
            % Gray code
            rowLabels = {'0', '1'};
            colLabels = {'00', '01', '11', '10'};
            
            % Initialize K-map
            kmap = zeros(rows, cols);
            
            % Minterm to K-map position mapping for 3 variables
            % ABC: A determines row, BC determines column
            mintermMap = [0 1 3 2; 4 5 7 6];
            
            % Fill K-map with minterms
            for m = app.Minterms
                if m >= 0 && m < 8
                    [row, col] = find(mintermMap == m);
                    if ~isempty(row)
                        kmap(row, col) = 1;
                    end
                end
            end
            
            % Draw the K-map
            app.KMapAxes.XLim = [0 cols];
            app.KMapAxes.YLim = [0 rows];
            app.KMapAxes.YDir = 'reverse';
            app.KMapAxes.XTick = 0.5:1:cols-0.5;
            app.KMapAxes.YTick = 0.5:1:rows-0.5;
            app.KMapAxes.XTickLabel = colLabels;
            app.KMapAxes.YTickLabel = rowLabels;
            grid(app.KMapAxes, 'on');
            
            xlabel(app.KMapAxes, 'BC');
            ylabel(app.KMapAxes, 'A');
            title(app.KMapAxes, '3-Variable K-Map');
            
            % Draw cells
            for i = 1:rows
                for j = 1:cols
                    rectangle(app.KMapAxes, 'Position', [j-1, i-1, 1, 1], ...
                        'FaceColor', [1 1 1], 'EdgeColor', 'k', 'LineWidth', 1.5);
                    
                    text(app.KMapAxes, j-0.5, i-0.5, num2str(kmap(i,j)), ...
                        'HorizontalAlignment', 'center', ...
                        'VerticalAlignment', 'middle', ...
                        'FontSize', 14, 'FontWeight', 'bold');
                end
            end
            
            app.KMapData = kmap;
        end
        
        function drawKMap4Var(app)
            % 4-variable K-map (4x4)
            rows = 4; cols = 4;
            
            % Gray code
            rowLabels = {'00', '01', '11', '10'};
            colLabels = {'00', '01', '11', '10'};
            
            % Initialize K-map
            kmap = zeros(rows, cols);
            
            % Minterm to K-map position mapping for 4 variables
            mintermMap = [0 1 3 2; 4 5 7 6; 12 13 15 14; 8 9 11 10];
            
            % Fill K-map with minterms
            for m = app.Minterms
                if m >= 0 && m < 16
                    [row, col] = find(mintermMap == m);
                    if ~isempty(row)
                        kmap(row, col) = 1;
                    end
                end
            end
            
            % Draw the K-map
            app.KMapAxes.XLim = [0 cols];
            app.KMapAxes.YLim = [0 rows];
            app.KMapAxes.YDir = 'reverse';
            app.KMapAxes.XTick = 0.5:1:cols-0.5;
            app.KMapAxes.YTick = 0.5:1:rows-0.5;
            app.KMapAxes.XTickLabel = colLabels;
            app.KMapAxes.YTickLabel = rowLabels;
            grid(app.KMapAxes, 'on');
            
            xlabel(app.KMapAxes, 'CD');
            ylabel(app.KMapAxes, 'AB');
            title(app.KMapAxes, '4-Variable K-Map');
            
            % Draw cells
            for i = 1:rows
                for j = 1:cols
                    rectangle(app.KMapAxes, 'Position', [j-1, i-1, 1, 1], ...
                        'FaceColor', [1 1 1], 'EdgeColor', 'k', 'LineWidth', 1.5);
                    
                    text(app.KMapAxes, j-0.5, i-0.5, num2str(kmap(i,j)), ...
                        'HorizontalAlignment', 'center', ...
                        'VerticalAlignment', 'middle', ...
                        'FontSize', 12, 'FontWeight', 'bold');
                end
            end
            
            app.KMapData = kmap;
        end
        
        function drawKMap5Var(app)
            % 5-variable K-map (two 4x4 maps)
            % This is more complex - we'll create two 4x4 maps side by side
            
            cla(app.KMapAxes);
            
            % We'll use subplots within the axes area
            % For simplicity, we'll draw them as two separate K-maps
            
            rows = 4; cols = 8; % Double width for two maps
            
            % Gray code
            rowLabels = {'00', '01', '11', '10'};
            colLabels = {'00', '01', '11', '10', '00', '01', '11', '10'};
            
            % Initialize K-maps (A=0 and A=1)
            kmap = zeros(rows, cols);
            
            % Minterm mapping for 5 variables
            % First map (A=0): minterms 0-15
            % Second map (A=1): minterms 16-31
            mintermMap1 = [0 1 3 2; 4 5 7 6; 12 13 15 14; 8 9 11 10];
            mintermMap2 = mintermMap1 + 16;
            
            % Fill K-maps with minterms
            for m = app.Minterms
                if m >= 0 && m < 16
                    [row, col] = find(mintermMap1 == m);
                    if ~isempty(row)
                        kmap(row, col) = 1;
                    end
                elseif m >= 16 && m < 32
                    [row, col] = find(mintermMap2 == m);
                    if ~isempty(row)
                        kmap(row, col+4) = 1;
                    end
                end
            end
            
            % Draw the K-maps
            app.KMapAxes.XLim = [0 cols];
            app.KMapAxes.YLim = [0 rows];
            app.KMapAxes.YDir = 'reverse';
            grid(app.KMapAxes, 'on');
            
            title(app.KMapAxes, '5-Variable K-Map (A=0 left, A=1 right)');
            
            % Draw cells
            for i = 1:rows
                for j = 1:cols
                    rectangle(app.KMapAxes, 'Position', [j-1, i-1, 1, 1], ...
                        'FaceColor', [1 1 1], 'EdgeColor', 'k', 'LineWidth', 1.5);
                    
                    text(app.KMapAxes, j-0.5, i-0.5, num2str(kmap(i,j)), ...
                        'HorizontalAlignment', 'center', ...
                        'VerticalAlignment', 'middle', ...
                        'FontSize', 10, 'FontWeight', 'bold');
                end
            end
            
            % Add dividing line between two maps
            line(app.KMapAxes, [4 4], [0 rows], 'Color', 'r', 'LineWidth', 2);
            
            % Add labels
            text(app.KMapAxes, 2, -0.5, 'A=0', 'HorizontalAlignment', 'center', ...
                'FontSize', 12, 'FontWeight', 'bold');
            text(app.KMapAxes, 6, -0.5, 'A=1', 'HorizontalAlignment', 'center', ...
                'FontSize', 12, 'FontWeight', 'bold');
            
            app.KMapData = kmap;
        end
        
        function simplifyExpression(app)
            % Basic simplification (this is a simplified version)
            % For a complete implementation, you'd need Quine-McCluskey algorithm
            
            varNames = {'A', 'B', 'C', 'D', 'E'};
            vars = varNames(1:app.NumVars);
            
            % Create simplified expression based on minterms
            if isempty(app.Minterms)
                app.SimplifiedExpressionTextArea.Value = '0';
                app.GroupsLabel.Text = 'Groups Found: None';
                return;
            end
            
            maxMinterm = 2^app.NumVars - 1;
            if all(ismember(0:maxMinterm, app.Minterms))
                app.SimplifiedExpressionTextArea.Value = '1';
                app.GroupsLabel.Text = 'Groups Found: Complete coverage';
                return;
            end
            
            % For demonstration, show sum of minterms
            expression = 'Sum of Minterms: ';
            for i = 1:length(app.Minterms)
                if i > 1
                    expression = [expression ' + '];
                end
                expression = [expression 'm' num2str(app.Minterms(i))];
            end
            
            app.SimplifiedExpressionTextArea.Value = expression;
            
            % Find and highlight groups (simplified version)
            numGroups = findGroups(app);
            app.GroupsLabel.Text = ['Groups Found: ' num2str(numGroups)];
        end
        
        function numGroups = findGroups(app)
            % Simplified group finding and highlighting
            numGroups = 0;
            
            if app.NumVars == 2
                % Check for pairs in 2x2 K-map
                kmap = app.KMapData;
                
                % Check horizontal pairs
                for i = 1:2
                    if kmap(i,1) == 1 && kmap(i,2) == 1
                        rectangle(app.KMapAxes, 'Position', [0, i-1, 2, 1], ...
                            'EdgeColor', 'r', 'LineWidth', 2, 'Curvature', 0.2, ...
                            'FaceColor', 'none');
                        numGroups = numGroups + 1;
                    end
                end
                
                % Check vertical pairs
                for j = 1:2
                    if kmap(1,j) == 1 && kmap(2,j) == 1
                        rectangle(app.KMapAxes, 'Position', [j-1, 0, 1, 2], ...
                            'EdgeColor', 'b', 'LineWidth', 2, 'Curvature', 0.2, ...
                            'FaceColor', 'none');
                        numGroups = numGroups + 1;
                    end
                end
                
                % Check for quad
                if all(kmap(:) == 1)
                    rectangle(app.KMapAxes, 'Position', [0, 0, 2, 2], ...
                        'EdgeColor', 'g', 'LineWidth', 3, 'Curvature', 0.2, ...
                        'FaceColor', 'none');
                    numGroups = 1; % Quad counts as one group
                end
            end
            
            % Similar logic would be implemented for 3, 4, and 5 variables
            % This is a simplified version for demonstration
        end
        
        function clearAll(app)
            % Clear all inputs and outputs
            app.TruthTableTextArea.Value = '';
            app.BooleanExpressionEditField.Value = '';
            app.SimplifiedExpressionTextArea.Value = '';
            app.GroupsLabel.Text = 'Groups Found:';
            cla(app.KMapAxes);
            app.Minterms = [];
            app.KMapData = [];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateKMapButton
        function GenerateKMapButtonPushed(app, event)
            generateKMap(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            clearAll(app);
        end

        % Value changed function: VariableCountDropDown
        function VariableCountDropDownValueChanged(app, event)
            clearAll(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DigitalLogicDesignTab = uitab(app.TabGroup);
            app.DigitalLogicDesignTab.Title = 'Digital Logic Design';
            app.UIAxes = uiaxes(app.DigitalLogicDesignTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.DigitalLogicDesignTab);
            title(app.UIAxes2, 'Output Waveform')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ANDButton = uibutton(app.DigitalLogicDesignTab, 'push');
            app.ANDButton.ButtonPushedFcn = createCallbackFcn(app, @ANDButtonPushed, true);
            app.ANDButton.Icon = fullfile(pathToMLAPP, 'AND.png');
            app.ANDButton.Text = 'AND';
            app.ORButton = uibutton(app.DigitalLogicDesignTab, 'push');
            app.ORButton.ButtonPushedFcn = createCallbackFcn(app, @ORButtonPushed, true);
            app.ORButton.Icon = fullfile(pathToMLAPP, 'OR.png');
            app.ORButton.Text = 'OR';
            app.NOTButton = uibutton(app.DigitalLogicDesignTab, 'push');
            app.NOTButton.ButtonPushedFcn = createCallbackFcn(app, @NOTButtonPushed, true);
            app.NOTButton.Icon = fullfile(pathToMLAPP, 'NOT.png');
            app.NOTButton.Text = 'NOT';
            app.XORButton = uibutton(app.DigitalLogicDesignTab, 'push');
            app.XORButton.ButtonPushedFcn = createCallbackFcn(app, @XORButtonPushed, true);
            app.XORButton.Icon = fullfile(pathToMLAPP, 'XOR.png');
            app.XORButton.Text = 'XOR';
            app.UITable = uitable(app.DigitalLogicDesignTab);
            app.UITable.ColumnName = {'INPUT-1'; 'INPUT-2'; 'OUTPUT'; 'Column 4'};
            app.UITable.RowName = {};
            app.NANDButton = uibutton(app.DigitalLogicDesignTab, 'push');
            app.NANDButton.ButtonPushedFcn = createCallbackFcn(app, @NANDButtonPushed, true);
            app.NANDButton.Icon = fullfile(pathToMLAPP, 'NAND.png');
            app.NANDButton.Text = 'NAND';
            app.NORButton = uibutton(app.DigitalLogicDesignTab, 'push');
            app.NORButton.ButtonPushedFcn = createCallbackFcn(app, @NORButtonPushed, true);
            app.NORButton.Icon = fullfile(pathToMLAPP, 'NOR.png');
            app.NORButton.Text = 'NOR';
            app.XNORButton = uibutton(app.DigitalLogicDesignTab, 'push');
            app.XNORButton.ButtonPushedFcn = createCallbackFcn(app, @XNORButtonPushed, true);
            app.XNORButton.Icon = fullfile(pathToMLAPP, 'Xnor1.png');
            app.XNORButton.Text = 'XNOR';
            app.TruthTableLabel = uilabel(app.DigitalLogicDesignTab);
            app.TruthTableLabel.Text = 'Truth Table';
            app.DigitalModulationTab = uitab(app.TabGroup);
            app.DigitalModulationTab.Title = 'Digital Modulation';
            app.SignalAxes = uiaxes(app.DigitalModulationTab);
            title(app.SignalAxes, 'Binary Signal & Modulated Waveform')
            xlabel(app.SignalAxes, 'X')
            ylabel(app.SignalAxes, 'Y')
            zlabel(app.SignalAxes, 'Z')
            app.ConstellationAxes = uiaxes(app.DigitalModulationTab);
            title(app.ConstellationAxes, 'Constellation Diagram')
            xlabel(app.ConstellationAxes, 'X')
            ylabel(app.ConstellationAxes, 'Y')
            zlabel(app.ConstellationAxes, 'Z')
            app.InputBinaryEditFieldLabel = uilabel(app.DigitalModulationTab);
            app.InputBinaryEditFieldLabel.Text = 'Input Binary';
            app.InputBinaryEditField = uieditfield(app.DigitalModulationTab, 'text');
            app.SelectModulationDropDownLabel = uilabel(app.DigitalModulationTab);
            app.SelectModulationDropDownLabel.Text = 'Select  Modulation ';
            app.SelectModulationDropDown = uidropdown(app.DigitalModulationTab);
            app.SelectModulationDropDown.Items = {'ASK', 'FSK', 'PSK', 'QPSK', 'BPSK', 'QAM'};
            app.SelectModulationDropDown.Value = 'ASK';
            app.SimulateModulationButton = uibutton(app.DigitalModulationTab, 'push');
            app.SimulateModulationButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateModulationButtonPushed, true);
            app.SimulateModulationButton.Text = 'Simulate/Modulation ';
            app.BEREditFieldLabel = uilabel(app.DigitalModulationTab);
            app.BEREditFieldLabel.Text = 'BER';
            app.BEREditField = uieditfield(app.DigitalModulationTab, 'numeric');
            app.AmplitudeEditFieldLabel = uilabel(app.DigitalModulationTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude ';
            app.AmplitudeEditField = uieditfield(app.DigitalModulationTab, 'text');
            app.PhaseEditFieldLabel = uilabel(app.DigitalModulationTab);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.DigitalModulationTab, 'text');
            app.KMAPTab = uitab(app.TabGroup);
            app.KMAPTab.SizeChangedFcn = createCallbackFcn(app, @generateKMap, true);
            app.KMAPTab.Title = 'KMAP';
            app.KMapAxes = uiaxes(app.KMAPTab);
            title(app.KMapAxes, 'KMAP')
            xlabel(app.KMapAxes, 'X')
            ylabel(app.KMapAxes, 'Y')
            zlabel(app.KMapAxes, 'Z')
            app.GenerateKMapButton = uibutton(app.KMAPTab, 'push');
            app.GenerateKMapButton.Text = 'Generate K-Map';
            app.GroupsLabel = uilabel(app.KMAPTab);
            app.GroupsLabel.Text = 'Groups Found';
            app.ClearButton = uibutton(app.KMAPTab, 'push');
            app.ClearButton.Text = 'Clear';
            app.NumberofVariablesDropDownLabel = uilabel(app.KMAPTab);
            app.NumberofVariablesDropDownLabel.Text = 'Number of Variables';
            app.VariableCountDropDown = uidropdown(app.KMAPTab);
            app.VariableCountDropDown.Items = {'2', '3', '4', '5'};
            app.VariableCountDropDown.Value = '2';
            app.BooleanExpressionEditFieldLabel = uilabel(app.KMAPTab);
            app.BooleanExpressionEditFieldLabel.Text = 'Boolean Expression';
            app.BooleanExpressionEditField = uieditfield(app.KMAPTab, 'text');
            app.BooleanExpressionEditField.Placeholder = 'Enter expression (e.g., A''B + BC)';
            app.TruthTableInputTextAreaLabel = uilabel(app.KMAPTab);
            app.TruthTableInputTextAreaLabel.Text = 'Truth Table Input';
            app.TruthTableTextArea = uitextarea(app.KMAPTab);
            app.TruthTableTextArea.Placeholder = 'Enter minterms (e.g., 0,1,3,7)';
            app.SimplifiedExpressionTextAreaLabel = uilabel(app.KMAPTab);
            app.SimplifiedExpressionTextAreaLabel.Text = 'Simplified Expression';
            app.SimplifiedExpressionTextArea = uitextarea(app.KMAPTab);
            app.DigitalLogicDigitalModulationLabel = uilabel(app.UIFigure);
            app.DigitalLogicDigitalModulationLabel.Text = 'Digital Logic & Digital Modulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DigitalSimApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end