classdef KarnaughMapSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ClearButton                     matlab.ui.control.Button
        GroupsLabel                     matlab.ui.control.Label
        SimplifiedExpressionTextArea    matlab.ui.control.TextArea
        SimplifiedExpressionTextAreaLabel  matlab.ui.control.Label
        GenerateKMapButton              matlab.ui.control.Button
        TruthTableTextArea              matlab.ui.control.TextArea
        TruthTableInputTextAreaLabel    matlab.ui.control.Label
        BooleanExpressionEditField      matlab.ui.control.EditField
        BooleanExpressionLabel          matlab.ui.control.Label
        VariableCountDropDown           matlab.ui.control.DropDown
        NumberofVariablesDropDownLabel  matlab.ui.control.Label
        KMapAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function generateKMap(app)
             % Get number of variables
            app.NumVars = str2double(app.VariableCountDropDown.Value);
            
            % Parse input (from truth table text area)
            mintermStr = app.TruthTableTextArea.Value;
            if ~isempty(mintermStr)
                try
                    % Parse minterms
                    app.Minterms = str2num(mintermStr{1});
                    
                    % Generate K-map
                    drawKMap(app);
                    
                    % Find prime implicants and simplify
                    simplifyExpression(app);
                catch
                    uialert(app.UIFigure, 'Invalid minterm format. Use comma-separated numbers.', 'Input Error');
                end
            else
                uialert(app.UIFigure, 'Please enter minterms', 'Input Error');
            end
        end
        
        function drawKMap(app)
            cla(app.KMapAxes);
            hold(app.KMapAxes, 'on');
            
            switch app.NumVars
                case 2
                    drawKMap2Var(app);
                case 3
                    drawKMap3Var(app);
                case 4
                    drawKMap4Var(app);
                case 5
                    drawKMap5Var(app);
            end
            
            hold(app.KMapAxes, 'off');
        end
        
        function drawKMap2Var(app)
            % 2-variable K-map (2x2)
            rows = 2; cols = 2;
            
            % Gray code for 2 variables
            rowLabels = {'0', '1'};
            colLabels = {'0', '1'};
            
            % Initialize K-map
            kmap = zeros(rows, cols);
            
            % Fill K-map with minterms
            for m = app.Minterms
                if m >= 0 && m < 4
                    row = floor(m/2) + 1;
                    col = mod(m, 2) + 1;
                    kmap(row, col) = 1;
                end
            end
            
            % Draw the K-map grid
            app.KMapAxes.XLim = [0 cols];
            app.KMapAxes.YLim = [0 rows];
            app.KMapAxes.YDir = 'reverse';
            app.KMapAxes.XTick = 0.5:1:cols-0.5;
            app.KMapAxes.YTick = 0.5:1:rows-0.5;
            app.KMapAxes.XTickLabel = colLabels;
            app.KMapAxes.YTickLabel = rowLabels;
            app.KMapAxes.GridAlpha = 0.5;
            grid(app.KMapAxes, 'on');
            
            % Add labels
            xlabel(app.KMapAxes, 'B');
            ylabel(app.KMapAxes, 'A');
            title(app.KMapAxes, '2-Variable K-Map');
            
            % Draw cells and values
            for i = 1:rows
                for j = 1:cols
                    % Draw rectangle
                    rectangle(app.KMapAxes, 'Position', [j-1, i-1, 1, 1], ...
                        'FaceColor', [1 1 1], 'EdgeColor', 'k', 'LineWidth', 1.5);
                    
                    % Add value
                    text(app.KMapAxes, j-0.5, i-0.5, num2str(kmap(i,j)), ...
                        'HorizontalAlignment', 'center', ...
                        'VerticalAlignment', 'middle', ...
                        'FontSize', 14, 'FontWeight', 'bold');
                end
            end
            
            app.KMapData = kmap;
        end
        
        function drawKMap3Var(app)
            % 3-variable K-map (2x4)
            rows = 2; cols = 4;
            
            % Gray code
            rowLabels = {'0', '1'};
            colLabels = {'00', '01', '11', '10'};
            
            % Initialize K-map
            kmap = zeros(rows, cols);
            
            % Minterm to K-map position mapping for 3 variables
            % ABC: A determines row, BC determines column
            mintermMap = [0 1 3 2; 4 5 7 6];
            
            % Fill K-map with minterms
            for m = app.Minterms
                if m >= 0 && m < 8
                    [row, col] = find(mintermMap == m);
                    if ~isempty(row)
                        kmap(row, col) = 1;
                    end
                end
            end
            
            % Draw the K-map
            app.KMapAxes.XLim = [0 cols];
            app.KMapAxes.YLim = [0 rows];
            app.KMapAxes.YDir = 'reverse';
            app.KMapAxes.XTick = 0.5:1:cols-0.5;
            app.KMapAxes.YTick = 0.5:1:rows-0.5;
            app.KMapAxes.XTickLabel = colLabels;
            app.KMapAxes.YTickLabel = rowLabels;
            grid(app.KMapAxes, 'on');
            
            xlabel(app.KMapAxes, 'BC');
            ylabel(app.KMapAxes, 'A');
            title(app.KMapAxes, '3-Variable K-Map');
            
            % Draw cells
            for i = 1:rows
                for j = 1:cols
                    rectangle(app.KMapAxes, 'Position', [j-1, i-1, 1, 1], ...
                        'FaceColor', [1 1 1], 'EdgeColor', 'k', 'LineWidth', 1.5);
                    
                    text(app.KMapAxes, j-0.5, i-0.5, num2str(kmap(i,j)), ...
                        'HorizontalAlignment', 'center', ...
                        'VerticalAlignment', 'middle', ...
                        'FontSize', 14, 'FontWeight', 'bold');
                end
            end
            
            app.KMapData = kmap;
        end
        
        function drawKMap4Var(app)
            % 4-variable K-map (4x4)
            rows = 4; cols = 4;
            
            % Gray code
            rowLabels = {'00', '01', '11', '10'};
            colLabels = {'00', '01', '11', '10'};
            
            % Initialize K-map
            kmap = zeros(rows, cols);
            
            % Minterm to K-map position mapping for 4 variables
            mintermMap = [0 1 3 2; 4 5 7 6; 12 13 15 14; 8 9 11 10];
            
            % Fill K-map with minterms
            for m = app.Minterms
                if m >= 0 && m < 16
                    [row, col] = find(mintermMap == m);
                    if ~isempty(row)
                        kmap(row, col) = 1;
                    end
                end
            end
            
            % Draw the K-map
            app.KMapAxes.XLim = [0 cols];
            app.KMapAxes.YLim = [0 rows];
            app.KMapAxes.YDir = 'reverse';
            app.KMapAxes.XTick = 0.5:1:cols-0.5;
            app.KMapAxes.YTick = 0.5:1:rows-0.5;
            app.KMapAxes.XTickLabel = colLabels;
            app.KMapAxes.YTickLabel = rowLabels;
            grid(app.KMapAxes, 'on');
            
            xlabel(app.KMapAxes, 'CD');
            ylabel(app.KMapAxes, 'AB');
            title(app.KMapAxes, '4-Variable K-Map');
            
            % Draw cells
            for i = 1:rows
                for j = 1:cols
                    rectangle(app.KMapAxes, 'Position', [j-1, i-1, 1, 1], ...
                        'FaceColor', [1 1 1], 'EdgeColor', 'k', 'LineWidth', 1.5);
                    
                    text(app.KMapAxes, j-0.5, i-0.5, num2str(kmap(i,j)), ...
                        'HorizontalAlignment', 'center', ...
                        'VerticalAlignment', 'middle', ...
                        'FontSize', 12, 'FontWeight', 'bold');
                end
            end
            
            app.KMapData = kmap;
        end
        
        function drawKMap5Var(app)
            % 5-variable K-map (two 4x4 maps)
            % This is more complex - we'll create two 4x4 maps side by side
            
            cla(app.KMapAxes);
            
            % We'll use subplots within the axes area
            % For simplicity, we'll draw them as two separate K-maps
            
            rows = 4; cols = 8; % Double width for two maps
            
            % Gray code
            rowLabels = {'00', '01', '11', '10'};
            colLabels = {'00', '01', '11', '10', '00', '01', '11', '10'};
            
            % Initialize K-maps (A=0 and A=1)
            kmap = zeros(rows, cols);
            
            % Minterm mapping for 5 variables
            % First map (A=0): minterms 0-15
            % Second map (A=1): minterms 16-31
            mintermMap1 = [0 1 3 2; 4 5 7 6; 12 13 15 14; 8 9 11 10];
            mintermMap2 = mintermMap1 + 16;
            
            % Fill K-maps with minterms
            for m = app.Minterms
                if m >= 0 && m < 16
                    [row, col] = find(mintermMap1 == m);
                    if ~isempty(row)
                        kmap(row, col) = 1;
                    end
                elseif m >= 16 && m < 32
                    [row, col] = find(mintermMap2 == m);
                    if ~isempty(row)
                        kmap(row, col+4) = 1;
                    end
                end
            end
            
            % Draw the K-maps
            app.KMapAxes.XLim = [0 cols];
            app.KMapAxes.YLim = [0 rows];
            app.KMapAxes.YDir = 'reverse';
            grid(app.KMapAxes, 'on');
            
            title(app.KMapAxes, '5-Variable K-Map (A=0 left, A=1 right)');
            
            % Draw cells
            for i = 1:rows
                for j = 1:cols
                    rectangle(app.KMapAxes, 'Position', [j-1, i-1, 1, 1], ...
                        'FaceColor', [1 1 1], 'EdgeColor', 'k', 'LineWidth', 1.5);
                    
                    text(app.KMapAxes, j-0.5, i-0.5, num2str(kmap(i,j)), ...
                        'HorizontalAlignment', 'center', ...
                        'VerticalAlignment', 'middle', ...
                        'FontSize', 10, 'FontWeight', 'bold');
                end
            end
            
            % Add dividing line between two maps
            line(app.KMapAxes, [4 4], [0 rows], 'Color', 'r', 'LineWidth', 2);
            
            % Add labels
            text(app.KMapAxes, 2, -0.5, 'A=0', 'HorizontalAlignment', 'center', ...
                'FontSize', 12, 'FontWeight', 'bold');
            text(app.KMapAxes, 6, -0.5, 'A=1', 'HorizontalAlignment', 'center', ...
                'FontSize', 12, 'FontWeight', 'bold');
            
            app.KMapData = kmap;
        end
        
        function simplifyExpression(app)
            % Basic simplification (this is a simplified version)
            % For a complete implementation, you'd need Quine-McCluskey algorithm
            
            varNames = {'A', 'B', 'C', 'D', 'E'};
            vars = varNames(1:app.NumVars);
            
            % Create simplified expression based on minterms
            if isempty(app.Minterms)
                app.SimplifiedExpressionTextArea.Value = '0';
                app.GroupsLabel.Text = 'Groups Found: None';
                return;
            end
            
            maxMinterm = 2^app.NumVars - 1;
            if all(ismember(0:maxMinterm, app.Minterms))
                app.SimplifiedExpressionTextArea.Value = '1';
                app.GroupsLabel.Text = 'Groups Found: Complete coverage';
                return;
            end
            
            % For demonstration, show sum of minterms
            expression = 'Sum of Minterms: ';
            for i = 1:length(app.Minterms)
                if i > 1
                    expression = [expression ' + '];
                end
                expression = [expression 'm' num2str(app.Minterms(i))];
            end
            
            app.SimplifiedExpressionTextArea.Value = expression;
            
            % Find and highlight groups (simplified version)
            numGroups = findGroups(app);
            app.GroupsLabel.Text = ['Groups Found: ' num2str(numGroups)];
        end
        
        function numGroups = findGroups(app)
            % Simplified group finding and highlighting
            numGroups = 0;
            
            if app.NumVars == 2
                % Check for pairs in 2x2 K-map
                kmap = app.KMapData;
                
                % Check horizontal pairs
                for i = 1:2
                    if kmap(i,1) == 1 && kmap(i,2) == 1
                        rectangle(app.KMapAxes, 'Position', [0, i-1, 2, 1], ...
                            'EdgeColor', 'r', 'LineWidth', 2, 'Curvature', 0.2, ...
                            'FaceColor', 'none');
                        numGroups = numGroups + 1;
                    end
                end
                
                % Check vertical pairs
                for j = 1:2
                    if kmap(1,j) == 1 && kmap(2,j) == 1
                        rectangle(app.KMapAxes, 'Position', [j-1, 0, 1, 2], ...
                            'EdgeColor', 'b', 'LineWidth', 2, 'Curvature', 0.2, ...
                            'FaceColor', 'none');
                        numGroups = numGroups + 1;
                    end
                end
                
                % Check for quad
                if all(kmap(:) == 1)
                    rectangle(app.KMapAxes, 'Position', [0, 0, 2, 2], ...
                        'EdgeColor', 'g', 'LineWidth', 3, 'Curvature', 0.2, ...
                        'FaceColor', 'none');
                    numGroups = 1; % Quad counts as one group
                end
            end
            
            % Similar logic would be implemented for 3, 4, and 5 variables
            % This is a simplified version for demonstration
        end
        
        function clearAll(app)
            % Clear all inputs and outputs
            app.TruthTableTextArea.Value = '';
            app.BooleanExpressionEditField.Value = '';
            app.SimplifiedExpressionTextArea.Value = '';
            app.GroupsLabel.Text = 'Groups Found:';
            cla(app.KMapAxes);
            app.Minterms = [];
            app.KMapData = [];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateKMapButton
        function GenerateKMapButtonPushed(app, event)
            generateKMap(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            clearAll(app);
        end

        % Value changed function: VariableCountDropDown
        function VariableCountDropDownValueChanged(app, event)
            clearAll(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KMapAxes = uiaxes(app.UIFigure);
            title(app.KMapAxes, 'Title')
            xlabel(app.KMapAxes, 'X')
            ylabel(app.KMapAxes, 'Y')
            zlabel(app.KMapAxes, 'Z')
            app.NumberofVariablesDropDownLabel = uilabel(app.UIFigure);
            app.NumberofVariablesDropDownLabel.Text = 'Number of Variables';
            app.VariableCountDropDown = uidropdown(app.UIFigure);
            app.VariableCountDropDown.Items = {'2', '3', '4', '5'};
            app.VariableCountDropDown.Value = '2';
            app.BooleanExpressionLabel = uilabel(app.UIFigure);
            app.BooleanExpressionLabel.Text = 'Boolean Expression';
            app.BooleanExpressionEditField = uieditfield(app.UIFigure, 'text');
            app.BooleanExpressionEditField.Placeholder = 'Enter expression (e.g., A''B + BC)';
            app.TruthTableInputTextAreaLabel = uilabel(app.UIFigure);
            app.TruthTableInputTextAreaLabel.Text = 'Truth Table Input';
            app.TruthTableTextArea = uitextarea(app.UIFigure);
            app.TruthTableTextArea.Placeholder = 'Enter minterms (e.g., 0,1,3,7)';
            app.GenerateKMapButton = uibutton(app.UIFigure, 'push');
            app.GenerateKMapButton.Text = 'Generate K-Map';
            app.SimplifiedExpressionTextAreaLabel = uilabel(app.UIFigure);
            app.SimplifiedExpressionTextAreaLabel.Text = 'Simplified Expression';
            app.SimplifiedExpressionTextArea = uitextarea(app.UIFigure);
            app.GroupsLabel = uilabel(app.UIFigure);
            app.GroupsLabel.Text = 'Groups Found';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KarnaughMapSolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @generateKMap)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end